classdef plate_hole < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        GridLayout    matlab.ui.container.GridLayout
        LeftPanel     matlab.ui.container.Panel
        x50x5mmLabel  matlab.ui.control.Label
        start         matlab.ui.control.StateButton
        p             matlab.ui.control.NumericEditField
        paLabel       matlab.ui.control.Label
        nu            matlab.ui.control.NumericEditField
        Label_3       matlab.ui.control.Label
        E             matlab.ui.control.NumericEditField
        Label         matlab.ui.control.Label
        r             matlab.ui.control.DropDown
        Label_6       matlab.ui.control.Label
        stop          matlab.ui.control.Button
        RightPanel    matlab.ui.container.Panel
        AuthorLabel   matlab.ui.control.Label
        clear         matlab.ui.control.Button
        a             matlab.ui.control.NumericEditField
        Label_5       matlab.ui.control.Label
        UIAxes        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        smodel=createpde("structural",'static-solid'); % Description
        p_value;
        img_h
    end
    
    methods (Access = private)
        
        function results = calculate(app,smodel,E,nu,p)
            msh= generateMesh(smodel);
            structuralProperties(smodel,'YoungsModulus',E,'PoissonsRatio',nu);
            structuralBC(smodel,'Face',2,'Constraint','fixed');
            structuralBC(smodel,'Face',3,'Constraint','fixed');
            structuralBoundaryLoad(smodel,'Face',4,'Pressure',-p);
            structuralBoundaryLoad(smodel,'Face',6,'Pressure',-p);
            Rs=solve(smodel);
            pdeplot3D(smodel, 'ColorMapData',Rs.VonMisesStress, 'Deformation',Rs.Displacement,'DeformationScaleFactor',1000)
            results=max(Rs.Stress.hoop)/mean(Rs.Stress.hoop);
        end
        

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: stop
        function stopPushed(app, event)
            fig = uifigure;
            confirm = uiconfirm(fig,'确定要退出吗？）','question');
            if strcmp(confirm,'OK')
             quit();
            end
        end

        % Value changed function: a
        function aValueChanged(app, event)
            value = app.a.Value;
            
        end

        % Value changed function: r
        function rValueChanged(app, event)
            value = app.r.Value;
            app.smodel=createpde("structural",'static-solid');
            switch value
                case {'r=1mm'}
                    importGeometry(app.smodel,'r=1mm.stl');
                case {'r=2mm'}
                    importGeometry(app.smodel,'r=2mm.stl');
                case {'r=3mm'}
                    importGeometry(app.smodel,'r=3mm.stl');
                case {'r=4mm'}
                    importGeometry(app.smodel,'r=4mm.stl');
                case {'r=5mm'}
                    importGeometry(app.smodel,'r=5mm.stl');
         end
        end

        % Value changed function: p
        function pValueChanged(app, event)
            app.p_value = app.p.Value;
            
        end

        % Value changed function: start
        function startValueChanged(app, event)
            E=app.E.Value;
            nu=app.nu.Value;
            p=app.p_value;
            app.a.Value = calculate(app,app.smodel,E,nu,p);
            hold(app.UIAxes,'on')
            grid(app.UIAxes,'on')
            app.img_h=plot(app.UIAxes,app.p_value,app.a.Value,"Color",'r','Marker','*');
            app.UIAxes.XTickLabel={'0','200','400','600','800','1000'};
            app.UIAxes.XLim=[0 1000]
            %app.UIAxes.XLabel='压强';
            app.UIAxes.YTickLabel={'0','0.5','1','1.5','2','2.5','3','3.5','4','4.5','5','5.5','6','6.5','7','7.5','8','8.5','9','9.5','10'};
            app.UIAxes.YTick=0 :0.5:10;
           % app.UIAxes.YLabe='应力集中系数';
        end

        % Button pushed function: clear
        function clearButtonPushed(app, event)
           delete(app.UIAxes);
           app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, '应力集中系数图')
            xlabel(app.UIAxes, '压强')
            ylabel(app.UIAxes, '应力集中系数')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 200 400 600 800 1000];
            app.UIAxes.XTickLabel = {'0'; '200'; '400'; '600'; '800'; '1000'};
            app.UIAxes.YTick = [0 0.35 0.7 1.05 1.4 1.75 2.1 2.45 2.8 3.15 3.5];
            app.UIAxes.YTickLabel = {'0'; '0.35'; '0.7'; '1.05'; '1.4'; '1.75'; '2.1'; '2.45'; '2.8'; '3.15'; '3.5'};
            app.UIAxes.Position = [47 126 352 309];
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.stop = uibutton(app.LeftPanel, 'push');
            app.stop.ButtonPushedFcn = createCallbackFcn(app, @stopPushed, true);
            app.stop.Text = '退出';
            app.Label_6 = uilabel(app.LeftPanel);
            app.Label_6.Text = '孔径(mm)';
            app.r = uidropdown(app.LeftPanel);
            app.r.Items = {'<请选择>', 'r=1mm', 'r=2mm', 'r=3mm', 'r=4mm', 'r=5mm'};
            app.r.ValueChangedFcn = createCallbackFcn(app, @rValueChanged, true);
            app.r.Value = '<请选择>';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '杨氏模量(Pa)';
            app.E = uieditfield(app.LeftPanel, 'numeric');
            app.Label_3 = uilabel(app.LeftPanel);
            app.Label_3.Text = '波松比';
            app.nu = uieditfield(app.LeftPanel, 'numeric');
            app.nu.Limits = [0 0.499999];
            app.paLabel = uilabel(app.LeftPanel);
            app.paLabel.Text = '压强（pa）';
            app.p = uieditfield(app.LeftPanel, 'numeric');
            app.p.ValueChangedFcn = createCallbackFcn(app, @pValueChanged, true);
            app.start = uibutton(app.LeftPanel, 'state');
            app.start.ValueChangedFcn = createCallbackFcn(app, @startValueChanged, true);
            app.start.Text = '开始';
            app.x50x5mmLabel = uilabel(app.LeftPanel);
            app.x50x5mmLabel.Text = '板件规格：50x50x5(mm)';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, '应力集中系数图')
            xlabel(app.UIAxes, '压强')
            ylabel(app.UIAxes, '应力集中系数')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 200 400 600 800 1000];
            app.UIAxes.XTickLabel = {'0'; '200'; '400'; '600'; '800'; '1000'};
            app.UIAxes.YTick = [0 0.35 0.7 1.05 1.4 1.75 2.1 2.45 2.8 3.15 3.5 3.85 4.2 4.55 4.9 5.25 5.6 5.95 6.3 6.65 7 7.35 7.7 8.05 8.4 8.75 9.1 9.45 9.8 10.15];
            app.UIAxes.YTickLabel = {'0'; '0.35'; '0.7'; '1.05'; '1.4'; '1.75'; '2.1'; '2.45'; '2.8'; '3.15'; '3.5'; '3.85'; '4.2'; '4.55'; '4.9'; '5.25'; '5.6'; '5.95'; '6.3'; '6.65'; '7'; '7.35'; '7.7'; '8.05'; '8.4'; '8.75'; '9.1'; '9.45'; '9.8'; '10.15'};
            app.Label_5 = uilabel(app.RightPanel);
            app.Label_5.Text = '应力集中系数';
            app.a = uieditfield(app.RightPanel, 'numeric');
            app.a.ValueChangedFcn = createCallbackFcn(app, @aValueChanged, true);
            app.clear = uibutton(app.RightPanel, 'push');
            app.clear.ButtonPushedFcn = createCallbackFcn(app, @clearButtonPushed, true);
            app.clear.Text = '清除页面';
            app.AuthorLabel = uilabel(app.RightPanel);
            app.AuthorLabel.Text = 'Author:韩慕一';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plate_hole

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end